classdef ML_PolynomialRegression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ClearAxesButton                matlab.ui.control.Button
        ImportDataButton               matlab.ui.control.Button
        PolynomialDeterminationButton  matlab.ui.control.Button
        AccuracyOrderSpinnerLabel      matlab.ui.control.Label
        AccuracyOrderSpinner           matlab.ui.control.Spinner
        PredictFuturerangepercKnobLabel  matlab.ui.control.Label
        PredictFuturerangepercKnob     matlab.ui.control.Knob
    end

    
    properties (Access = private)
        c; % Description
        x;
        p;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            a=readtable('tumhisse.xlsx');
            b=table2array(a(:,2));
            app.c=b'; % ' koymasanda olur
            plot(app.UIAxes,app.c);
            hold(app.UIAxes);
        end

        % Button pushed function: PolynomialDeterminationButton
        function PolynomialDeterminationButtonPushed(app, event)
            app.x=1:length(app.c);
            app.p=polyfit(app.x,app.c,app.AccuracyOrderSpinner.Value);       
            prdct=polyval(app.p,app.x);
            plot(app.UIAxes,prdct);
        end

        % Button pushed function: ClearAxesButton
        function ClearAxesButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Value changed function: PredictFuturerangepercKnob
        function PredictFuturerangepercKnobValueChanged(app, event)
            value = app.PredictFuturerangepercKnob.Value;
            future=polyval(app.p,1:app.x(end)+value);
            plot(app.UIAxes,future);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ClearAxesButton = uibutton(app.UIFigure, 'push');
            app.ClearAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAxesButtonPushed, true);
            app.ClearAxesButton.Text = 'Clear Axes';
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.PolynomialDeterminationButton = uibutton(app.UIFigure, 'push');
            app.PolynomialDeterminationButton.ButtonPushedFcn = createCallbackFcn(app, @PolynomialDeterminationButtonPushed, true);
            app.PolynomialDeterminationButton.Text = 'Polynomial Determination';
            app.AccuracyOrderSpinnerLabel = uilabel(app.UIFigure);
            app.AccuracyOrderSpinnerLabel.Text = 'Accuracy- Order';
            app.AccuracyOrderSpinner = uispinner(app.UIFigure);
            app.AccuracyOrderSpinner.Limits = [0 25];
            app.AccuracyOrderSpinner.Value = 5;
            app.PredictFuturerangepercKnobLabel = uilabel(app.UIFigure);
            app.PredictFuturerangepercKnobLabel.Text = 'Predict Future range perc.';
            app.PredictFuturerangepercKnob = uiknob(app.UIFigure, 'continuous');
            app.PredictFuturerangepercKnob.Limits = [0 20];
            app.PredictFuturerangepercKnob.ValueChangedFcn = createCallbackFcn(app, @PredictFuturerangepercKnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ML_PolynomialRegression

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end